classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WormBehaviorAnalysisUIFigure  matlab.ui.Figure
        DataAnalysisPanel             matlab.ui.container.Panel
        WormSegmentationPanel         matlab.ui.container.Panel
        BinarySlider                  matlab.ui.control.Slider
        WormAreaEditFieldLabel        matlab.ui.control.Label
        WormAreaEditField             matlab.ui.control.NumericEditField
        PreSegmentButton              matlab.ui.control.Button
        BinaryEditFieldLabel          matlab.ui.control.Label
        BinaryEditField               matlab.ui.control.NumericEditField
        WormSegmentButton             matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        startEditFieldLabel           matlab.ui.control.Label
        startEditField                matlab.ui.control.NumericEditField
        endEditFieldLabel             matlab.ui.control.Label
        endEditField                  matlab.ui.control.NumericEditField
        LoadDataPanel                 matlab.ui.container.Panel
        VideoEditFieldLabel           matlab.ui.control.Label
        VideoEditField                matlab.ui.control.EditField
        LoadButton                    matlab.ui.control.Button
        ImagesEditFieldLabel          matlab.ui.control.Label
        ImagesEditField               matlab.ui.control.EditField
        SelectButton                  matlab.ui.control.Button
        DataFolderTextAreaLabel       matlab.ui.control.Label
        DataFolderTextArea            matlab.ui.control.TextArea
        WormPositionsEditFieldLabel   matlab.ui.control.Label
        WormPositionsEditField        matlab.ui.control.EditField
        LoadPosButton                 matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        FrameRateEditFieldLabel       matlab.ui.control.Label
        FrameRateEditField            matlab.ui.control.NumericEditField
        CenterlineLocalizationPanel   matlab.ui.container.Panel
        CalculateCenterlineButton     matlab.ui.control.Button
        DrawCenterlineButton          matlab.ui.control.Button
        StartReverseIndexEdit         matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        EndReverseIndexEdit           matlab.ui.control.NumericEditField
        ReverseCenterlineButton       matlab.ui.control.Button
        ExportErrorImagesButton       matlab.ui.control.Button
        UpdateCenterlineButton        matlab.ui.control.Button
        WormFeaturesPanel             matlab.ui.container.Panel
        LengthCheckBox                matlab.ui.control.CheckBox
        CurvatureCheckBox             matlab.ui.control.CheckBox
        BendingCheckBox               matlab.ui.control.CheckBox
        OmegaAngleCheckBox            matlab.ui.control.CheckBox
        ForwardBackwardCheckBox       matlab.ui.control.CheckBox
        CalcFeaturesButton            matlab.ui.control.Button
        PauseStateThresholdEditFieldLabel  matlab.ui.control.Label
        PauseStateThresholdEditField  matlab.ui.control.NumericEditField
        PixelsWormpausesifoffsetthisvalueLabel  matlab.ui.control.Label
        WormvelocityButtonGroup       matlab.ui.container.ButtonGroup
        RawButton                     matlab.ui.control.RadioButton
        SmoothButton                  matlab.ui.control.RadioButton
        SmoothtimesEditFieldLabel     matlab.ui.control.Label
        SmoothtimesEditField          matlab.ui.control.NumericEditField
        UseCenterlinePointCheckBox    matlab.ui.control.CheckBox
        CenterlinePointEditField      matlab.ui.control.NumericEditField
        HeadBendAngleCheckBox         matlab.ui.control.CheckBox
        HeadTailDeterminationPanel    matlab.ui.container.Panel
        ClickandpressENTERLabel       matlab.ui.control.Label
        SelectHeadPositionButton_2    matlab.ui.control.Button
    end


    properties (Access = private)
        DATA_PROCESSING_FOLDER = '.\Data_Processed\'; % The main folder for all data
        Current_Folder = '';
        BINARY_THRES = 128;
        WORM_AREA = 7500;
        Image_Num = 0;
        Pre_Seg_Index = 0;
        WORM_SEG_FLAG = 0;
        CENTERLINE_CALC_FLAG = 0;
        IN_TRACKING = 0;
        FRAME_RATE = 10;
        Worm_Head_Pos = [nan, nan]; %[y,x] 用于确定中心线的头尾, 可通过鼠标点击确定，无需设置
    end

    methods (Access = public)
    
        function show_message(app,text,title)
            h = msgbox(text,title);
            ah = get( h, 'CurrentAxes' );
            ch = get( ah, 'Children' );
            set( ch, 'FontSize', 10 );
            
            boxPosition = get(h,'Position');
            center_x = boxPosition(1) + boxPosition(3)/2;
            center_y = boxPosition(2) + boxPosition(4)/2;
            new_width = boxPosition(3)*1.35;
            new_height = boxPosition(4)*1.1;
            set(h,'Position',[center_x - new_width/2 center_y - new_height/2 new_width new_height]);
            boxPosition = get(h,'Position');
            
            % make the text center
            th = findobj(h, 'Type', 'text');
            textPosition = get(th, 'Position'); 
            set(th, 'Position', [boxPosition(3).*0.5 textPosition(2) textPosition(3)]);
            set(th, 'HorizontalAlignment', 'center');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check the Data Processed folder, and if the folder does not exist, then make it
            if ~exist(app.DATA_PROCESSING_FOLDER,'dir')
                mkdir(app.DATA_PROCESSING_FOLDER);
            end
            app.FRAME_RATE = app.FrameRateEditField.Value;
            app.IN_TRACKING = 0;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load video and convert video into images
            [FileName,PathName] = uigetfile('*.avi','Select Video');
            video_name = [PathName FileName];
            app.VideoEditField.Value = video_name;
            
            % Step 1, create folder in data processing folder
            folder_num = length(dir(app.DATA_PROCESSING_FOLDER)) - 2;
            app.Current_Folder = [app.DATA_PROCESSING_FOLDER 'Data-' num2str(folder_num+1)];
            if ~exist(app.Current_Folder,'dir')
                mkdir(app.Current_Folder);
                app.Current_Folder = [app.Current_Folder '\'];
                
                % make directories for images, backbone, worm region and centerline
                mkdir([app.Current_Folder 'images']);
                mkdir([app.Current_Folder 'worm_region']);
                mkdir([app.Current_Folder 'centerline']);
                mkdir([app.Current_Folder 'backbone']);
            end
            
            % Set label in centerline localization panel
            app.DataFolderTextArea.Value = app.Current_Folder;
            
            % Step 3, convert video into images
            show_message(app, 'Start to extract all images in video','Info');
            
            readObj = VideoReader(video_name);
            folder = [app.Current_Folder 'images\'];
            k = 0;
            while hasFrame(readObj)
                img = readFrame(readObj);
                if size(img,3) > 1
                    img = rgb2gray(img);
                end
                disp(['Read image: ' num2str(k)]);
                imwrite(img, [folder num2str(k) '.tiff']);
                k = k+1;
            end
            app.Image_Num = k;
            
            show_message(app, 'All images in video have been extracted','Info');
        end

        % Button pushed function: CalcFeaturesButton
        function CalcFeaturesButtonPushed(app, event)
            % Check all data has been processed
            close all;
            font_size = 12; % figure parameters
            line_width = 1.5;
            
            if app.Image_Num == 0 || app.CENTERLINE_CALC_FLAG == 0
                show_message(app, 'No images loaded or centerline have not been calculated !', 'Warning');
                return;
            end
            
            % Load data
            Centerline_Folder = [app.Current_Folder 'centerline\'];
            if app.IN_TRACKING
                worm_pos_data = load([app.Current_Folder 'WormPositionsInTracking.mat']);
                disp('Load worm positions in tracking');
            else
                worm_pos_data = load([app.Current_Folder 'WormRegionPos.mat']);
                disp('Load worm positions without tracking');
            end
           
            worm_pos = worm_pos_data.worm_pos;
            SkipList = load([app.Current_Folder 'backbone\skiplist.txt']);
            delta_time = 1/app.FrameRateEditField.Value;
            time = (1:app.Image_Num)*delta_time;
            
            disp(SkipList);
            
            % calculate worm length
            if app.LengthCheckBox.Value == true
                num = min(50, app.Image_Num);
                length_list = zeros(num,1);
                for i=1:num
                    if ~isempty(find(SkipList == (i-1), 1))
                        continue;
                    end
                    data = load([Centerline_Folder num2str(i-1) '.mat']);
                    centerline = data.centerline;
                    length_list(i) = sum(sqrt(sum((centerline(2:end,:) - centerline(1:end-1,:)).^2,2)));
                end
                worm_length = mean(length_list);
                disp(['Worm length: ' num2str(worm_length)]);
                save([app.Current_Folder 'worm_length.mat'], 'worm_length');
            end
            
            % caluclate curvatures
            curvatures = Worm_Curvatures(Centerline_Folder, app.Image_Num, SkipList);
            save([app.Current_Folder 'curvatures.mat'],'curvatures');
            
            % calculate bending
            if app.BendingCheckBox.Value == true
                bends = zeros(app.Image_Num,1);
                for i=1:app.Image_Num
                    if ~isempty(find(SkipList == (i-1), 1))
                        bends(i) = nan;
                        continue;
                    end
                    data = load([Centerline_Folder num2str(i-1) '.mat']);
                    centerline = data.centerline;
                    bends(i) = CalculateWormBend(centerline);
                end
                save([app.Current_Folder 'bendings.mat'],'bends');
            end
            
            % analyze the state of motion: forward, backward and pause
            %[FB, STATE] = Find_FB_V0(curvatures, worm_pos, app.FRAME_RATE);
            PAUSE_THRESHOLD = app.PauseStateThresholdEditField.Value;
            head_direction = calc_head_direction(app.Current_Folder);
            [FB, STATE] = Find_FB_V1(worm_pos, head_direction, PAUSE_THRESHOLD, app.FRAME_RATE);
            [forward_stat, backward_stat, pause_stat] = Analyze_FB(FB,STATE);
            save([app.Current_Folder 'forward_backward_pause_states.mat'],...
                'FB', 'STATE', 'delta_time', 'head_direction', 'worm_pos', ...
                'forward_stat', 'backward_stat', 'pause_stat');
            
            % calculate omega angle
            if app.OmegaAngleCheckBox.Value == true
                [omega_angles,omega_state] = calc_omega_angle(Centerline_Folder,app.FRAME_RATE);
                save([app.Current_Folder 'omega_angles.mat'],'omega_angles','omega_state');
            end
            
            % calculate velocity
            if  app.UseCenterlinePointCheckBox.Value == true
                centerline_point = app.CenterlinePointEditField.Value;
                worm_speed = ComputeSpeed_ByCenterline(app.Current_Folder,0:app.Image_Num-1,...
                    centerline_point,delta_time);
            else
                worm_speed = sqrt(sum((worm_pos(2:end,:) - worm_pos(1:end-1,:)).^2,2))/delta_time;
            end
            worm_speed = RemoveOutlier(worm_speed);
            backward_state = (FB == STATE.BACKWARD);
            worm_speed(backward_state(2:end)) = -worm_speed(backward_state(2:end));
            
            % process the speed of skipped images
            for i=1:length(SkipList)
                l = (SkipList(i)-2):(SkipList(i)+2);
                l(l < 1) = 1;
                l(l > app.Image_Num-1) = app.Image_Num-1;
                worm_speed(l) = nan;
            end
            
            if app.SmoothButton.Value == true
                worm_speed = smooth(worm_speed, app.SmoothtimesEditField.Value*app.FRAME_RATE, 'moving');
            end     
            save([app.Current_Folder 'worm_speed'], 'worm_speed');
             
            % draw worm velocity
            h1 = figure;
            plot(time(2:end),worm_speed,'b-','LineWidth',line_width);hold on;
            xlabel('Time (s)', 'FontSize', font_size);
            ylabel('Worm Speed (Pixels/s)', 'FontSize', font_size);
            set(gca,'FontSize',font_size);
            hold off;
            print(h1, '-djpeg', '-r200', [app.Current_Folder 'worm_speed']);
            
            % worm bends
            if app.BendingCheckBox.Value == true
                h2 = figure;
                plot(time,bends,'b-','LineWidth',line_width);hold on;
                xlabel('Time (s)', 'FontSize', font_size);
                ylabel('Bending', 'FontSize', font_size);
                set(gca,'FontSize',font_size);
                hold off;
                print(h2, '-djpeg', '-r200', [app.Current_Folder 'worm_bend']);
            end
            
            % draw curvatures
            h3 = figure;
            imagesc(curvatures');colormap(jet);
            xlabel('Frame');
            ylabel('Body coordinate');
            set(gca,'FontSize',font_size);
            print(h3, '-djpeg', '-r200', [app.Current_Folder 'curvatures']);
        end

        % Button pushed function: PreSegmentButton
        function PreSegmentButtonPushed(app, event)
            app.BINARY_THRES = app.BinarySlider.Value;
            app.WORM_AREA = app.WormAreaEditField.Value;
            app.BinaryEditField.Value = floor(app.BINARY_THRES);
            
            app.Pre_Seg_Index = app.startEditField.Value;
            
            % Load 0-th image and show the binary image
            if ~exist([app.Current_Folder 'images\' num2str(app.Pre_Seg_Index) '.tiff'],'file')
                show_message(app, 'No images loaded', 'Warning');
                return;
            end
            
            img = double(imread([app.Current_Folder 'images\' num2str(app.Pre_Seg_Index) '.tiff']));
            [height, width] = size(img);
            worm_region = [1, height, 1, width];
            [binary_image,area,~,new_worm_region] = worm_seg_single(img, app.BINARY_THRES, worm_region, app.WORM_AREA);
            
            img = img(new_worm_region(1):new_worm_region(2),new_worm_region(3):new_worm_region(4));
            rgb_img = uint8(zeros(length(binary_image(:,1)),length(binary_image(1,:)),3));
            rgb_img(:,:,1) = uint8(img)+uint8(200*binary_image);
            rgb_img(:,:,2) = uint8(img);
            rgb_img(:,:,3) = uint8(img);
%             imshow(binary_image);colormap(gray);axis image;
            imshow(rgb_img);colormap(gray);axis image;
            % update binary threshold and worm area
            app.WormAreaEditField.Value = floor(area);
        end

        % Button pushed function: CalculateCenterlineButton
        function CalculateCenterlineButtonPushed(app, event)
            if app.Image_Num == 0 || app.WORM_SEG_FLAG == 0
                show_message(app, 'No images loaded', 'Warning');
                return;
            end
%             
%             % use worm_cv.exe to caculate centerlines of all binary images
            Calculate_Centerline([app.Current_Folder 'worm_region'], [app.Current_Folder 'backbone'], 0, app.Image_Num-1);
            
            % load backbone results and generate centerline in original image space
            Centerline_Folder = [app.Current_Folder 'centerline\'];
            worm_pos_data = load([app.Current_Folder 'WormRegionPos.mat']);
            worm_region = worm_pos_data.worm_regions;
            
            Start_Index = 0;
            End_Index = app.Image_Num-1;
            is_reverse = 0;
            
            
            for i=Start_Index:End_Index
                disp(['Processing ceterline backbone file ' num2str(i)]);
                
                backbone_name = [app.Current_Folder 'backbone\backbone_' num2str(i) '.bin'];
                % whether backbone file exists
                if ~exist(backbone_name,'file')
                    continue;
                end
                
                % load backbine file
        	backbone = LoadCenterlineResults(backbone_name);
                if ~backbone.length_error
                    centerline = backbone.current_backbone;               
                else
                    centerline = backbone.last_backbone;
                end
                
                % read configure file and get partition number
                config;
                centerline = spline_fitting_partition(centerline, Partition_Num);
                
                % add worm region to centerline
                % centerline = centerline + repmat([worm_region(i-Start_Index+1,1) worm_region(i-Start_Index+1,3)],length(centerline),1);
                if i <= Start_Index+1 && ~isnan(app.Worm_Head_Pos(1))
                    head_dist = sum((centerline(1,:) - app.Worm_Head_Pos+[worm_region(1,1) worm_region(1,3)]).^2,2);
        	    tail_dist = sum((centerline(length(centerline),:) - app.Worm_Head_Pos+[worm_region(1,1) worm_region(1,3)]).^2,2);
        	    if (tail_dist < head_dist)
        	        is_reverse = 1;
        	    end
                end
                
                if is_reverse
                    % reverse centerline
            	    centerline = centerline(end:-1:1,:);
                end
                
                % save centerline
                centerline_name = [Centerline_Folder num2str(i) '.mat'];
                save(centerline_name,'centerline');
                filename = [app.Current_Folder 'centerline_txt\' num2str(i) '.txt'];
                dump_centerline(centerline,filename);
            end
            app.CENTERLINE_CALC_FLAG = 1;
        end

        % Button pushed function: WormSegmentButton
        function WormSegmentButtonPushed(app, event)
            if app.Image_Num == 0
                show_message(app, 'No images loaded', 'Warning');
                return;
            end
            
%             % segment all images in the video
%             show_message(app, 'Start to segment all worm images in video/images','Info');
            
            app.BINARY_THRES = app.BinarySlider.Value;
            app.WORM_AREA = app.WormAreaEditField.Value;
            start_index = app.startEditField.Value;
            end_index = app.endEditField.Value;
            worm_seg([app.Current_Folder 'images\'], app.BINARY_THRES, app.WORM_AREA, app.Current_Folder,start_index,end_index);
            app.WORM_SEG_FLAG = 1;
            
%             show_message(app, 'All worm images have been segmented','Info');
        end

        % Button pushed function: DrawCenterlineButton
        function DrawCenterlineButtonPushed(app, event)
%             if app.Image_Num == 0 || app.CENTERLINE_CALC_FLAG == 0
%                 show_message(app, 'No images loaded or centerline have not been calculated!', 'Warning');
%                 return;
%             end
%             close all;
            
            % Draw centerline in current axes
            Image_Folder = [app.Current_Folder 'images\'];
            Centerline_Folder = [app.Current_Folder 'centerline\'];
            worm_pos = load([app.Current_Folder 'WormRegionPos.mat']);
            worm_region = worm_pos.worm_regions;
            frame_rate = app.FRAME_RATE;
            video_name = [app.Current_Folder 'processed_video'];
            
            % Parameters setting
            image_format = '.tiff';
            image_names = dir([Image_Folder, '*' image_format]);
            Start_Index = 0;
            End_Index = length(image_names)-1;
            
            line_width = 1.2;  % figure paramters
            marker_size = 12;
            
%             writerObj = VideoWriter([video_name '.mp4'],'MPEG-4'); % make video
%             writerObj.FrameRate = frame_rate;
%             open(writerObj);
            
            figure;
            for i=Start_Index:End_Index
                centerline_name = [Centerline_Folder num2str(i) '.mat'];
                % because skip list, some centerlie file does not exist
                if ~exist(centerline_name, 'file')
                    continue;
                end
                data = load(centerline_name);
                centerline = data.centerline;
                
                % load image 
                image_name = [Image_Folder num2str(i) image_format];
                img = imread(image_name);
                worm_img = img(worm_region(i-Start_Index+1,1):worm_region(i-Start_Index+1,2),...
                    worm_region(i-Start_Index+1,3):worm_region(i-Start_Index+1,4));
                
                % draw image and plot centerline
                imshow(worm_img);colormap(gray);axis image;hold on;
                plot(centerline(:,2),centerline(:,1),'b-','LineWidth',line_width);
                plot(centerline(1,2),centerline(1,1),'r.','MarkerSize',marker_size,'LineWidth',line_width);
                hold off;
                title(['Image ' num2str(i)]);
                if ~exist([app.Current_Folder 'fig\'])
                    mkdir([app.Current_Folder 'fig\']);
                end
                saveas(gcf,[app.Current_Folder 'fig\' num2str(i) image_format]);
            
%                 % write this frame into video
%                 current_figure = getframe(gcf);
%                 region = current_figure.cdata;
%                 writeVideo(writerObj,region);
            end
%             close(writerObj);
        end

        % Button pushed function: ReverseCenterlineButton
        function ReverseCenterlineButtonPushed(app, event)
            % reverse centerline
            if app.Image_Num == 0 || app.CENTERLINE_CALC_FLAG == 0
                show_message(app, 'No images loaded or centerline have not been calculated!', 'Warning');
                return;
            end
            
            Centerline_Folder = [app.Current_Folder 'centerline\'];
            start_index = app.StartReverseIndexEdit.Value;
            end_index = app.EndReverseIndexEdit.Value;
            reverse_centerline(Centerline_Folder,start_index:end_index);
            
            % show messagebox
            show_message(app, ['Centerlines from ' num2str(start_index) ' to ' num2str(end_index) ' have been reversed'], 'Info');
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            folder_name = uigetdir;
            folder_name = [folder_name '\'];
            app.ImagesEditField.Value = folder_name;
            
            % check whether the folder contains images, worm region, centerine and backbone
            if ~exist([folder_name 'images'],'dir')
                show_message(app, 'No images folder', 'Error');
                return;
            end
            app.Current_Folder = folder_name;
            app.DataFolderTextArea.Value = app.Current_Folder;
            
            % make some directories
            if ~exist([folder_name 'worm_region'],'dir')
                mkdir([app.Current_Folder 'worm_region']);
            end
            if ~exist([folder_name 'backbone'],'dir')
                mkdir([app.Current_Folder 'backbone']);
            end
            if ~exist([folder_name 'centerline'],'dir')
                mkdir([app.Current_Folder 'centerline']);
            end
            if ~exist([folder_name 'centerline_txt'],'dir')
                mkdir([app.Current_Folder 'centerline_txt']);
            end
            
            app.Image_Num = length(dir([app.Current_Folder 'images\*.tiff']));
            
            % if worm images have been segmented then set to be 1
            if (length(dir([app.Current_Folder 'worm_region\*.tiff']))) == app.Image_Num
                app.WORM_SEG_FLAG = 1;
                disp('Load segmented worm images');
            end
        end

        % Button pushed function: LoadPosButton
        function LoadPosButtonPushed(app, event)
            % Read tracking output and load state positions
            folder_name = uigetdir;
            image_folder = [folder_name '\'];
           
            if isempty(app.Current_Folder) || app.Image_Num <= 0
                show_message(app, 'Please load video or select images firstly', 'Warning');
                return;
            end
            
            tracked_image_folder = [app.Current_Folder 'tracked_images\'];
            if ~exist([app.Current_Foldername 'tracked_images'],'dir')
                mkdir([app.Current_Folder 'tracked_images']);
            end
            
            % start to convert images
            show_message(app, 'Start to load worm positions', 'Info');
            
            [motion_offset, raw_worm_pos] = ImageConvert(image_folder,tracked_image_folder);
            worm_pos = WormPos_Filtering(raw_worm_pos);
            app.IN_TRACKING = 1;
            
            show_message(app, 'Finish to load worm positions', 'Info');
            save([app.Current_Folder 'WormPositionsInTracking.mat'],'motion_offset','worm_pos', 'raw_worm_pos');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % pre-segment the previous image
            if (app.Pre_Seg_Index == 0)
                app.Button.Enable = 'off';
                app.Button_2.Enable = 'on';
            else
                if (strcmp(app.Button_2.Enable, 'off') == 1)
                    app.Button_2.Enable = 'on';
                end
                app.Pre_Seg_Index = app.Pre_Seg_Index - 1;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % pre-segment the next image
            if (app.Pre_Seg_Index == app.Image_Num-1)
                app.Button.Enable = 'on';
                app.Button_2.Enable = 'off';
            else
                if (strcmp(app.Button.Enable, 'off') == 1)
                    app.Button.Enable = 'on';
                end
                app.Pre_Seg_Index = app.Pre_Seg_Index + 1;
            end
        end

        % Value changed function: BinaryEditField
        function BinaryEditFieldValueChanged(app, event)
            value = app.BinaryEditField.Value;
            app.BinarySlider.Value = value;
        end

        % Button pushed function: SelectHeadPositionButton_2
        function SelectHeadPositionButtonValueChanged(app, event)
            % use mouse to determine worm head
            if ~exist([app.Current_Folder 'images'],'dir') || app.Image_Num == 0
                show_message(app, 'No images loaded', 'Error');
                return;
            end
            
            %close all
            img = imread([app.Current_Folder 'images\' num2str(0) '.tiff']);
            imshow(img);colormap(gray);axis image;
            
            [x,y] = ginput;
            disp(['Clicked position is [' num2str(x) ', ' num2str(y) ']']);
            app.Worm_Head_Pos = [y,x];
        end

        % Selection changed function: WormvelocityButtonGroup
        function WormvelocityButtonGroupSelectionChanged(app, event)
            selectedButton = app.WormvelocityButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Raw') == 1
                app.SmoothtimesEditField.Enable = 'off';
                app.SmoothtimesEditFieldLabel.Enable = 'off';
                
            elseif strcmp(selectedButton.Text, 'Smooth') == 1
                app.SmoothtimesEditField.Enable = 'on';
                app.SmoothtimesEditFieldLabel.Enable = 'on';
            end
            
        end

        % Button pushed function: ExportErrorImagesButton
        function ExportErrorImagesButtonPushed(app, event)
             if ~isempty(app.Current_Folder)
                ExportErrorImages(app.Current_Folder);
            end
        end

        % Button pushed function: UpdateCenterlineButton
        function UpdateCenterlineButtonPushed(app, event)
            if ~isempty(app.Current_Folder)
                UpdateCenterline_ByHand(app.Current_Folder);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WormBehaviorAnalysisUIFigure = uifigure;
            app.WormBehaviorAnalysisUIFigure.Name = 'Worm Behavior Analysis';
            app.DataAnalysisPanel = uipanel(app.WormBehaviorAnalysisUIFigure);
            app.DataAnalysisPanel.Title = 'Data Analysis';
            app.WormSegmentationPanel = uipanel(app.DataAnalysisPanel);
            app.WormSegmentationPanel.Title = 'Worm Segmentation';
            app.BinarySlider = uislider(app.WormSegmentationPanel);
            app.BinarySlider.Limits = [0 255];
            app.BinarySlider.MinorTicks = [];
            app.BinarySlider.Value = 128;
            app.WormAreaEditFieldLabel = uilabel(app.WormSegmentationPanel);
            app.WormAreaEditFieldLabel.Text = 'Worm Area';
            app.WormAreaEditField = uieditfield(app.WormSegmentationPanel, 'numeric');
            app.WormAreaEditField.Limits = [0 Inf];
            app.WormAreaEditField.ValueDisplayFormat = '%.0f';
            app.WormAreaEditField.Value = 7000;
            app.PreSegmentButton = uibutton(app.WormSegmentationPanel, 'push');
            app.PreSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @PreSegmentButtonPushed, true);
            app.PreSegmentButton.Text = 'Pre-Segment';
            app.BinaryEditFieldLabel = uilabel(app.WormSegmentationPanel);
            app.BinaryEditFieldLabel.Text = 'Binary';
            app.BinaryEditField = uieditfield(app.WormSegmentationPanel, 'numeric');
            app.BinaryEditField.Limits = [0 255];
            app.BinaryEditField.ValueDisplayFormat = '%.0f';
            app.BinaryEditField.ValueChangedFcn = createCallbackFcn(app, @BinaryEditFieldValueChanged, true);
            app.BinaryEditField.Value = 120;
            app.WormSegmentButton = uibutton(app.WormSegmentationPanel, 'push');
            app.WormSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @WormSegmentButtonPushed, true);
            app.WormSegmentButton.Text = 'Worm Segment';
            app.Button = uibutton(app.WormSegmentationPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.WormSegmentationPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.startEditFieldLabel = uilabel(app.WormSegmentationPanel);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.WormSegmentationPanel, 'numeric');
            app.startEditField.Limits = [0 Inf];
            app.endEditFieldLabel = uilabel(app.WormSegmentationPanel);
            app.endEditFieldLabel.Text = 'end';
            app.endEditField = uieditfield(app.WormSegmentationPanel, 'numeric');
            app.endEditField.Limits = [0 Inf];
            app.LoadDataPanel = uipanel(app.DataAnalysisPanel);
            app.LoadDataPanel.Title = 'Load Data';
            app.VideoEditFieldLabel = uilabel(app.LoadDataPanel);
            app.VideoEditFieldLabel.Text = 'Video';
            app.VideoEditField = uieditfield(app.LoadDataPanel, 'text');
            app.VideoEditField.Editable = 'off';
            app.LoadButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ImagesEditFieldLabel = uilabel(app.LoadDataPanel);
            app.ImagesEditFieldLabel.Text = 'Images';
            app.ImagesEditField = uieditfield(app.LoadDataPanel, 'text');
            app.ImagesEditField.Value = 'image name should be xxx.tiff starting from 0';
            app.SelectButton = uibutton(app.LoadDataPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.DataFolderTextAreaLabel = uilabel(app.LoadDataPanel);
            app.DataFolderTextAreaLabel.Text = 'Data Folder';
            app.DataFolderTextArea = uitextarea(app.LoadDataPanel);
            app.DataFolderTextArea.Editable = 'off';
            app.WormPositionsEditFieldLabel = uilabel(app.LoadDataPanel);
            app.WormPositionsEditFieldLabel.Text = 'Worm Positions';
            app.WormPositionsEditField = uieditfield(app.LoadDataPanel, 'text');
            app.WormPositionsEditField.Value = 'load images recorded by WormTracker';
            app.LoadPosButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadPosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPosButtonPushed, true);
            app.LoadPosButton.Text = 'LoadPos';
            app.Label_2 = uilabel(app.LoadDataPanel);
            app.Label_2.Text = 'Click [ Record ] to save images and stage positions when tracking at 10Hz';
            app.FrameRateEditFieldLabel = uilabel(app.LoadDataPanel);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.LoadDataPanel, 'numeric');
            app.FrameRateEditField.Limits = [0 50];
            app.FrameRateEditField.Value = 10;
            app.CenterlineLocalizationPanel = uipanel(app.DataAnalysisPanel);
            app.CenterlineLocalizationPanel.Title = 'Centerline Localization';
            app.CalculateCenterlineButton = uibutton(app.CenterlineLocalizationPanel, 'push');
            app.CalculateCenterlineButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCenterlineButtonPushed, true);
            app.CalculateCenterlineButton.Text = 'Calculate Centerline';
            app.DrawCenterlineButton = uibutton(app.CenterlineLocalizationPanel, 'push');
            app.DrawCenterlineButton.ButtonPushedFcn = createCallbackFcn(app, @DrawCenterlineButtonPushed, true);
            app.DrawCenterlineButton.Text = 'Draw Centerline';
            app.StartReverseIndexEdit = uieditfield(app.CenterlineLocalizationPanel, 'numeric');
            app.StartReverseIndexEdit.Limits = [0 Inf];
            app.Label = uilabel(app.CenterlineLocalizationPanel);
            app.Label.Text = '-';
            app.EndReverseIndexEdit = uieditfield(app.CenterlineLocalizationPanel, 'numeric');
            app.EndReverseIndexEdit.Limits = [0 Inf];
            app.ReverseCenterlineButton = uibutton(app.CenterlineLocalizationPanel, 'push');
            app.ReverseCenterlineButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseCenterlineButtonPushed, true);
            app.ReverseCenterlineButton.Text = 'Reverse Centerline';
            app.ExportErrorImagesButton = uibutton(app.CenterlineLocalizationPanel, 'push');
            app.ExportErrorImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportErrorImagesButtonPushed, true);
            app.ExportErrorImagesButton.Text = 'Export Error Images';
            app.UpdateCenterlineButton = uibutton(app.CenterlineLocalizationPanel, 'push');
            app.UpdateCenterlineButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCenterlineButtonPushed, true);
            app.UpdateCenterlineButton.Text = 'Update Centerline';
            app.WormFeaturesPanel = uipanel(app.DataAnalysisPanel);
            app.WormFeaturesPanel.Title = 'Worm Features';
            app.LengthCheckBox = uicheckbox(app.WormFeaturesPanel);
            app.LengthCheckBox.Text = 'Length';
            app.LengthCheckBox.Value = true;
            app.CurvatureCheckBox = uicheckbox(app.WormFeaturesPanel);
            app.CurvatureCheckBox.Text = 'Curvature';
            app.CurvatureCheckBox.Value = true;
            app.BendingCheckBox = uicheckbox(app.WormFeaturesPanel);
            app.BendingCheckBox.Text = 'Bending';
            app.BendingCheckBox.Value = true;
            app.OmegaAngleCheckBox = uicheckbox(app.WormFeaturesPanel);
            app.OmegaAngleCheckBox.Text = 'Omega Angle';
            app.OmegaAngleCheckBox.Value = true;
            app.ForwardBackwardCheckBox = uicheckbox(app.WormFeaturesPanel);
            app.ForwardBackwardCheckBox.Text = 'Forward/Backward ';
            app.ForwardBackwardCheckBox.Value = true;
            app.CalcFeaturesButton = uibutton(app.WormFeaturesPanel, 'push');
            app.CalcFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @CalcFeaturesButtonPushed, true);
            app.CalcFeaturesButton.Text = 'Calc Features';
            app.PauseStateThresholdEditFieldLabel = uilabel(app.WormFeaturesPanel);
            app.PauseStateThresholdEditFieldLabel.Text = 'Pause State Threshold';
            app.PauseStateThresholdEditField = uieditfield(app.WormFeaturesPanel, 'numeric');
            app.PauseStateThresholdEditField.Limits = [0 Inf];
            app.PauseStateThresholdEditField.ValueDisplayFormat = '%.0f';
            app.PauseStateThresholdEditField.Value = 3;
            app.PixelsWormpausesifoffsetthisvalueLabel = uilabel(app.WormFeaturesPanel);
            app.PixelsWormpausesifoffsetthisvalueLabel.Text = 'Pixels. Worm pauses if offset < this value';
            app.WormvelocityButtonGroup = uibuttongroup(app.WormFeaturesPanel);
            app.WormvelocityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WormvelocityButtonGroupSelectionChanged, true);
            app.WormvelocityButtonGroup.Title = 'Worm velocity';
            app.RawButton = uiradiobutton(app.WormvelocityButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.SmoothButton = uiradiobutton(app.WormvelocityButtonGroup);
            app.SmoothButton.Text = 'Smooth';
            app.SmoothtimesEditFieldLabel = uilabel(app.WormvelocityButtonGroup);
            app.SmoothtimesEditFieldLabel.Enable = 'off';
            app.SmoothtimesEditFieldLabel.Text = 'Smooth time (s)';
            app.SmoothtimesEditField = uieditfield(app.WormvelocityButtonGroup, 'numeric');
            app.SmoothtimesEditField.Limits = [0 Inf];
            app.SmoothtimesEditField.ValueDisplayFormat = '%.0f';
            app.SmoothtimesEditField.Enable = 'off';
            app.SmoothtimesEditField.Value = 1;
            app.UseCenterlinePointCheckBox = uicheckbox(app.WormvelocityButtonGroup);
            app.UseCenterlinePointCheckBox.Text = 'Use Centerline Point';
            app.CenterlinePointEditField = uieditfield(app.WormvelocityButtonGroup, 'numeric');
            app.CenterlinePointEditField.Limits = [0 1];
            app.CenterlinePointEditField.ValueDisplayFormat = '%11.2g';
            app.CenterlinePointEditField.Value = 0.5;
            app.HeadBendAngleCheckBox = uicheckbox(app.WormFeaturesPanel);
            app.HeadBendAngleCheckBox.Text = 'Head Bend Angle';
            app.HeadTailDeterminationPanel = uipanel(app.DataAnalysisPanel);
            app.HeadTailDeterminationPanel.Title = 'Head-Tail Determination';
            app.ClickandpressENTERLabel = uilabel(app.HeadTailDeterminationPanel);
            app.ClickandpressENTERLabel.Text = 'Click and press ENTER';
            app.SelectHeadPositionButton_2 = uibutton(app.HeadTailDeterminationPanel, 'push');
            app.SelectHeadPositionButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectHeadPositionButtonValueChanged, true);
            app.SelectHeadPositionButton_2.Text = 'Select Head Position';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WormBehaviorAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WormBehaviorAnalysisUIFigure)
        end
    end
end